classdef G8RobotArm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        FileMenu                    matlab.ui.container.Menu
        ReadDataMenu                matlab.ui.container.Menu
        DifferentiateMenu           matlab.ui.container.Menu
        CreateRobotMenu             matlab.ui.container.Menu
        HardwareMenu                matlab.ui.container.Menu
        InitArduinoMenu             matlab.ui.container.Menu
        TestArduinoMenu             matlab.ui.container.Menu
        Joint1SpinnerLabel          matlab.ui.control.Label
        Joint1Spinner               matlab.ui.control.Spinner
        Joint2SpinnerLabel          matlab.ui.control.Label
        Joint2Spinner               matlab.ui.control.Spinner
        Joint3SpinnerLabel          matlab.ui.control.Label
        Joint3Spinner               matlab.ui.control.Spinner
        TestKeyPressEditFieldLabel  matlab.ui.control.Label
        TestKeyPressEditField       matlab.ui.control.EditField
        DHparametersPanel           matlab.ui.container.Panel
        a1EditFieldLabel            matlab.ui.control.Label
        a1EditField                 matlab.ui.control.NumericEditField
        a2EditFieldLabel            matlab.ui.control.Label
        a2EditField                 matlab.ui.control.NumericEditField
        a3EditFieldLabel            matlab.ui.control.Label
        a3EditField                 matlab.ui.control.NumericEditField
        alpha1EditFieldLabel        matlab.ui.control.Label
        alpha1EditField             matlab.ui.control.NumericEditField
        alpha2EditFieldLabel        matlab.ui.control.Label
        alpha2EditField             matlab.ui.control.NumericEditField
        alpha3EditFieldLabel        matlab.ui.control.Label
        alpha3EditField             matlab.ui.control.NumericEditField
        d1EditFieldLabel            matlab.ui.control.Label
        d1EditField                 matlab.ui.control.NumericEditField
        d2EditFieldLabel            matlab.ui.control.Label
        d2EditField                 matlab.ui.control.NumericEditField
        d3EditFieldLabel            matlab.ui.control.Label
        d3EditField                 matlab.ui.control.NumericEditField
        EndEffectorLocationPanel    matlab.ui.container.Panel
        XEditFieldLabel             matlab.ui.control.Label
        XEditField                  matlab.ui.control.NumericEditField
        YEditFieldLabel             matlab.ui.control.Label
        YEditField                  matlab.ui.control.NumericEditField
        ZEditFieldLabel             matlab.ui.control.Label
        ZEditField                  matlab.ui.control.NumericEditField
        DistanceEditFieldLabel      matlab.ui.control.Label
        DistanceEditField           matlab.ui.control.NumericEditField
        PoseDropDownLabel           matlab.ui.control.Label
        PoseDropDown                matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        DataRead = false; % Initialize as false
        Data = []; %buffer for reading data
        DiffData = [];
        arduino_hardware = [];
        arduino_initialized = true;
        G8Robot = [];
        IsRobotCreated = false;

        % some useful poses
        Pose1 = [0 0 0];
        Pose2 = [0 pi/2 0];
        Pose3 = [0 -pi/2 0];
        Pose4 = [pi/2 0 0];
        Pose5 = [pi/2 pi/2 0];
        Pose6 = [pi/2 -pi/2 0];
        Pose7 = [-pi/2 0 0];
        Pose8 = [-pi/2 pi/2 0];
        Pose9 = [-pi/2 -pi/2 0];
        %Pose = [Pose1;Pose2;Pose3;Pose4;Pose5;Pose6;Pose7;Pose8;Pose9]
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            value = app.PoseDropDown.Value;
            if value == "Pose1_000"
                app.Joint1Spinner.Value = 0;
                app.Joint2Spinner.Value = 0;
                app.Joint3Spinner.Value = 0;
            elseif value == "Pose2_090"
                app.Joint1Spinner.Value = 0;
                app.Joint2Spinner.Value = 90;
                app.Joint3Spinner.Value = 0;
            elseif value == "Pose3_0-90"
                app.Joint1Spinner.Value = 0;
                app.Joint2Spinner.Value = -90;
                app.Joint3Spinner.Value = 0;
            elseif value == "Pose4_900"
                app.Joint1Spinner.Value = 90;
                app.Joint2Spinner.Value = 0;
                app.Joint3Spinner.Value = 0;
            elseif value == "Pose5_990"
                app.Joint1Spinner.Value = 90;
                app.Joint2Spinner.Value = 90;
                app.Joint3Spinner.Value = 0;
            elseif value == "Pose6_9-90"
                app.Joint1Spinner.Value = 90;
                app.Joint2Spinner.Value = -90;
                app.Joint3Spinner.Value = 0;
            elseif value == "Pose7_-900"
                app.Joint1Spinner.Value = -90;
                app.Joint2Spinner.Value = 0;
                app.Joint3Spinner.Value = 0;
            elseif value == "Pose8_-990"
                app.Joint1Spinner.Value = -90;
                app.Joint2Spinner.Value = 90;
                app.Joint3Spinner.Value = 0;
            elseif value == "Pose9_-9-90"
                app.Joint1Spinner.Value = -90;
                app.Joint2Spinner.Value = -90;
                app.Joint3Spinner.Value = 0;
            else
                app.Joint1Spinner.Value = 0;
                app.Joint2Spinner.Value = 0;
                app.Joint3Spinner.Value = 0;
            end
        end

        % Menu selected function: InitArduinoMenu
        function Initialize(app, event)
            try
                app.arduino_hardware = arduino();
            catch
                msgbox('error on hardware initialize');
                return;
            end
            app.arduino_initialized = true;
            msgbox('Arduino initialized');
        end

        % Menu selected function: TestArduinoMenu
        function TestArduino(app, event)
            if(app.arduino_initialized)
                app.arduino_hardware.writeDigitalPin("D13",1);
            end
        end

        % Menu selected function: ReadDataMenu
        function ReadData(app, event)
            [fn pn] = uigetfile('*.txt', 'Where is the data?');
            filename = [pn fn];
            app.Data = textread(filename);
            plot(app.UIAxes, app.Data(:,1),app.Data(:,2));
            app.DataRead = true;
            app.DataReadLamp.Color = [0.0 1.0 0.0];
            app.InfoTextArea.Value = 'Data has been read.';
        end

        % Menu selected function: DifferentiateMenu
        function Differentiate(app, event)
            if(app.DataRead)
                app.DiffData = app.Data;
                app.DiffData(1:999,2) = diff(app.Data(:,2));
                hold(app.UIAxes,'on');
                plot(app.UIAxes, app.DiffData(:,1),app.DiffData(:,2),'g');
                app.InfoTextArea.Value = 'Green is the differential';
            else
                app.InfoTextArea.Value = 'Read the data first';
            end
        end

        % Menu selected function: CreateRobotMenu
        function CreateRobot(app, event)
            clear L
            deg = pi/180;
            
            % all parameters are in SI units: m, radians, kg, kg.m2, N.m, N.m.s etc.
            L(1) = Revolute('d', app.d1EditField.Value, ...   % link length (Dennavit-Hartenberg notation)
                'a', app.a1EditField.Value, ...               % link offset (Dennavit-Hartenberg notation)
                'alpha', app.alpha1EditField.Value*deg, ...        % link twist (Dennavit-Hartenberg notation)
                'qlim', [-120 90]*deg ); % minimum and maximum joint angle
            
            L(2) = Revolute('d', app.d2EditField.Value, 'a', app.a2EditField.Value, 'alpha', app.alpha2EditField.Value*deg, ...
                'qlim', [-120 90]*deg );
            
            L(3) = Revolute('d', app.d3EditField.Value, 'a', app.a3EditField.Value, 'alpha', app.alpha3EditField.Value*deg,  ...
                'qlim', [-0 135]*deg );

            
            app.G8Robot = SerialLink(L, 'name', 'G8 Robot Arm', ...
                'tool',transl(0,0,0), ...
                'base',transl(0,0,0));

            app.G8Robot.plot([app.Joint1Spinner.Value*pi/180 ...
                app.Joint2Spinner.Value*pi/180 ...
                app.Joint3Spinner.Value*pi/180]);
            app.IsRobotCreated = true;
            
            T = app.G8Robot.fkine([app.Joint1Spinner.Value*pi/180 ...
                app.Joint2Spinner.Value*pi/180 ...
                app.Joint3Spinner.Value*pi/180]);
            
            app.XEditField.Value = T.t(1,1);
            app.YEditField.Value = T.t(2,1);
            app.ZEditField.Value = T.t(3,1);
            
            %Calculate the distance of EE
            X = app.XEditField.Value;
            Y = app.YEditField.Value;
            Z = app.ZEditField.Value;
            app.DistanceEditField.Value = sqrt(X*X + Y*Y + Z*Z);

        end

        % Value changed function: Joint1Spinner
        function MoveJoint1(app, event)
            %Re-plot robot after joint1 angle changed
            app.G8Robot.plot([app.Joint1Spinner.Value*pi/180 ...
                app.Joint2Spinner.Value*pi/180 ...
                app.Joint3Spinner.Value*pi/180]);
            %Recalculate the Transformation matrix after joint angle
            %changed
            T = app.G8Robot.fkine([app.Joint1Spinner.Value*pi/180 ...
                app.Joint2Spinner.Value*pi/180 ...
                app.Joint3Spinner.Value*pi/180]);
            %Update the EE position
            app.XEditField.Value = T.t(1,1);
            app.YEditField.Value = T.t(2,1);
            app.ZEditField.Value = T.t(3,1);
            
            %Calculate the distance of EE
            X = app.XEditField.Value;
            Y = app.YEditField.Value;
            Z = app.ZEditField.Value;
            app.DistanceEditField.Value = sqrt(X*X + Y*Y + Z*Z);
            
        end

        % Value changed function: Joint2Spinner
        function MoveJoint2(app, event)
            app.G8Robot.plot([app.Joint1Spinner.Value*pi/180 ...
                app.Joint2Spinner.Value*pi/180 ...
                app.Joint3Spinner.Value*pi/180]);
            %Recalculate the Transformation matrix after joint angle
            %changed
            T = app.G8Robot.fkine([app.Joint1Spinner.Value*pi/180 ...
                app.Joint2Spinner.Value*pi/180 ...
                app.Joint3Spinner.Value*pi/180]);
            %Update the EE position
            app.XEditField.Value = T.t(1,1);
            app.YEditField.Value = T.t(2,1);
            app.ZEditField.Value = T.t(3,1);
            
            %Calculate the distance of EE
            X = app.XEditField.Value;
            Y = app.YEditField.Value;
            Z = app.ZEditField.Value;
            app.DistanceEditField.Value = sqrt(X*X + Y*Y + Z*Z);
        end

        % Value changed function: Joint3Spinner
        function MoveJoint3(app, event)
            app.G8Robot.plot([app.Joint1Spinner.Value*pi/180 ...
                app.Joint2Spinner.Value*pi/180 ...
                app.Joint3Spinner.Value*pi/180]);
            %Recalculate the Transformation matrix after joint angle
            %changed
            T = app.G8Robot.fkine([app.Joint1Spinner.Value*pi/180 ...
                app.Joint2Spinner.Value*pi/180 ...
                app.Joint3Spinner.Value*pi/180]);
            %Update the EE position
            app.XEditField.Value = T.t(1,1);
            app.YEditField.Value = T.t(2,1);
            app.ZEditField.Value = T.t(3,1);
            
            %Calculate the distance of EE
            X = app.XEditField.Value;
            Y = app.YEditField.Value;
            Z = app.ZEditField.Value;
            app.DistanceEditField.Value = sqrt(X*X + Y*Y + Z*Z);
        end

        % Value changed function: a1EditField
        function Update_a1(app, event)
            value = app.a1EditField.Value;
            if app.IsRobotCreated == true
                clear L
                deg = pi/180;
                
                % all parameters are in SI units: m, radians, kg, kg.m2, N.m, N.m.s etc.
                L(1) = Revolute('d', app.d1EditField.Value, ...   % link length (Dennavit-Hartenberg notation)
                    'a', app.a1EditField.Value, ...               % link offset (Dennavit-Hartenberg notation)
                    'alpha', app.alpha1EditField.Value*deg, ...        % link twist (Dennavit-Hartenberg notation)
                    'qlim', [-120 90]*deg ); % minimum and maximum joint angle
                
                L(2) = Revolute('d', app.d2EditField.Value, 'a', app.a2EditField.Value, 'alpha', app.alpha2EditField.Value*deg, ...
                    'qlim', [-120 90]*deg );
                
                L(3) = Revolute('d', app.d3EditField.Value, 'a', app.a3EditField.Value, 'alpha', app.alpha3EditField.Value*deg,  ...
                    'qlim', [-0 135]*deg );
    
                
                app.G8Robot = SerialLink(L, 'name', 'G8 Robot Arm', ...
                    'configs', {'qz', app.qz, 'qr', app.qr, 'qs', app.qs, 'qn', app.qn}, ...
                    'tool',transl(0,0,0), ...
                    'base',transl(0,0,0));
    
                app.G8Robot.plot([app.Joint1Spinner.Value ...
                    app.Joint2Spinner.Value ...
                    app.Joint3Spinner.Value]);

            end
        end

        % Key release function: UIFigure
        function UIFigureKeyRelease(app, event)
            key = event.Key;
            app.TestKeyPressEditField.Value = key;
        end

        % Value changed function: PoseDropDown
        function ChangePose(app, event)
            value = app.PoseDropDown.Value;
            if value == "Pose1_000"
                app.Joint1Spinner.Value = 0;
                app.Joint2Spinner.Value = 0;
                app.Joint3Spinner.Value = 0;
            elseif value == "Pose2_090"
                app.Joint1Spinner.Value = 0;
                app.Joint2Spinner.Value = 90;
                app.Joint3Spinner.Value = 0;
            elseif value == "Pose3_0-90"
                app.Joint1Spinner.Value = 0;
                app.Joint2Spinner.Value = -90;
                app.Joint3Spinner.Value = 0;
            elseif value == "Pose4_900"
                app.Joint1Spinner.Value = 90;
                app.Joint2Spinner.Value = 0;
                app.Joint3Spinner.Value = 0;
            elseif value == "Pose5_990"
                app.Joint1Spinner.Value = 90;
                app.Joint2Spinner.Value = 90;
                app.Joint3Spinner.Value = 0;
            elseif value == "Pose6_9-90"
                app.Joint1Spinner.Value = 90;
                app.Joint2Spinner.Value = -90;
                app.Joint3Spinner.Value = 0;
            elseif value == "Pose7_-900"
                app.Joint1Spinner.Value = -90;
                app.Joint2Spinner.Value = 0;
                app.Joint3Spinner.Value = 0;
            elseif value == "Pose8_-990"
                app.Joint1Spinner.Value = -90;
                app.Joint2Spinner.Value = 90;
                app.Joint3Spinner.Value = 0;
            elseif value == "Pose9_-9-90"
                app.Joint1Spinner.Value = -90;
                app.Joint2Spinner.Value = -90;
                app.Joint3Spinner.Value = 0;
            else
                app.Joint1Spinner.Value = 0;
                app.Joint2Spinner.Value = 0;
                app.Joint3Spinner.Value = 0;
            end
            app.G8Robot.plot([app.Joint1Spinner.Value*pi/180 ...
                app.Joint2Spinner.Value*pi/180 ...
                app.Joint3Spinner.Value*pi/180]);
            %Recalculate the Transformation matrix after joint angle
            %changed
            T = app.G8Robot.fkine([app.Joint1Spinner.Value*pi/180 ...
                app.Joint2Spinner.Value*pi/180 ...
                app.Joint3Spinner.Value*pi/180]);
            %Update the EE position
            app.XEditField.Value = T.t(1,1);
            app.YEditField.Value = T.t(2,1);
            app.ZEditField.Value = T.t(3,1);
            %Calculate the distance of EE
            X = app.XEditField.Value;
            Y = app.YEditField.Value;
            Z = app.ZEditField.Value;
            app.DistanceEditField.Value = sqrt(X*X + Y*Y + Z*Z);
        end

        % Value changed function: XEditField
        function xPosChange(app, event)
            %Calculate the distance of EE
            X = app.XEditField.Value;
            Y = app.YEditField.Value;
            Z = app.ZEditField.Value;
            app.DistanceEditField.Value = sqrt(X*X + Y*Y + Z*Z);
        end

        % Value changed function: YEditField
        function yPosChange(app, event)
            %Calculate the distance of EE
            X = app.XEditField.Value;
            Y = app.YEditField.Value;
            Z = app.ZEditField.Value;
            app.DistanceEditField.Value = sqrt(X*X + Y*Y + Z*Z);
        end

        % Value changed function: ZEditField
        function zPosChange(app, event)
            %Calculate the distance of EE
            X = app.XEditField.Value;
            Y = app.YEditField.Value;
            Z = app.ZEditField.Value;
            app.DistanceEditField.Value = sqrt(X*X + Y*Y + Z*Z);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @UIFigureKeyRelease, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.ReadDataMenu = uimenu(app.FileMenu);
            app.ReadDataMenu.MenuSelectedFcn = createCallbackFcn(app, @ReadData, true);
            app.ReadDataMenu.Text = 'ReadData';
            app.DifferentiateMenu = uimenu(app.FileMenu);
            app.DifferentiateMenu.MenuSelectedFcn = createCallbackFcn(app, @Differentiate, true);
            app.DifferentiateMenu.Text = 'Differentiate';
            app.CreateRobotMenu = uimenu(app.FileMenu);
            app.CreateRobotMenu.MenuSelectedFcn = createCallbackFcn(app, @CreateRobot, true);
            app.CreateRobotMenu.Text = 'CreateRobot';
            app.HardwareMenu = uimenu(app.UIFigure);
            app.HardwareMenu.Text = 'Hardware';
            app.InitArduinoMenu = uimenu(app.HardwareMenu);
            app.InitArduinoMenu.MenuSelectedFcn = createCallbackFcn(app, @Initialize, true);
            app.InitArduinoMenu.Text = 'InitArduino';
            app.TestArduinoMenu = uimenu(app.HardwareMenu);
            app.TestArduinoMenu.MenuSelectedFcn = createCallbackFcn(app, @TestArduino, true);
            app.TestArduinoMenu.Text = 'TestArduino';
            app.Joint1SpinnerLabel = uilabel(app.UIFigure);
            app.Joint1SpinnerLabel.Text = 'Joint1';
            app.Joint1Spinner = uispinner(app.UIFigure);
            app.Joint1Spinner.Step = 10;
            app.Joint1Spinner.Limits = [-90 90];
            app.Joint1Spinner.ValueChangedFcn = createCallbackFcn(app, @MoveJoint1, true);
            app.Joint2SpinnerLabel = uilabel(app.UIFigure);
            app.Joint2SpinnerLabel.Text = 'Joint2';
            app.Joint2Spinner = uispinner(app.UIFigure);
            app.Joint2Spinner.Step = 10;
            app.Joint2Spinner.Limits = [-90 90];
            app.Joint2Spinner.ValueChangedFcn = createCallbackFcn(app, @MoveJoint2, true);
            app.Joint3SpinnerLabel = uilabel(app.UIFigure);
            app.Joint3SpinnerLabel.Text = 'Joint3';
            app.Joint3Spinner = uispinner(app.UIFigure);
            app.Joint3Spinner.Step = 10;
            app.Joint3Spinner.Limits = [-90 90];
            app.Joint3Spinner.ValueChangedFcn = createCallbackFcn(app, @MoveJoint3, true);
            app.TestKeyPressEditFieldLabel = uilabel(app.UIFigure);
            app.TestKeyPressEditFieldLabel.Text = {'TestKeyPress'; ''};
            app.TestKeyPressEditField = uieditfield(app.UIFigure, 'text');
            app.DHparametersPanel = uipanel(app.UIFigure);
            app.DHparametersPanel.Title = 'DH parameters';
            app.a1EditFieldLabel = uilabel(app.DHparametersPanel);
            app.a1EditFieldLabel.Text = 'a1';
            app.a1EditField = uieditfield(app.DHparametersPanel, 'numeric');
            app.a1EditField.ValueChangedFcn = createCallbackFcn(app, @Update_a1, true);
            app.a1EditField.Value = 0.1;
            app.a2EditFieldLabel = uilabel(app.DHparametersPanel);
            app.a2EditFieldLabel.Text = 'a2';
            app.a2EditField = uieditfield(app.DHparametersPanel, 'numeric');
            app.a2EditField.Value = 0.073;
            app.a3EditFieldLabel = uilabel(app.DHparametersPanel);
            app.a3EditFieldLabel.Text = 'a3';
            app.a3EditField = uieditfield(app.DHparametersPanel, 'numeric');
            app.alpha1EditFieldLabel = uilabel(app.DHparametersPanel);
            app.alpha1EditFieldLabel.Text = 'alpha1';
            app.alpha1EditField = uieditfield(app.DHparametersPanel, 'numeric');
            app.alpha2EditFieldLabel = uilabel(app.DHparametersPanel);
            app.alpha2EditFieldLabel.Text = 'alpha2';
            app.alpha2EditField = uieditfield(app.DHparametersPanel, 'numeric');
            app.alpha2EditField.Value = -90;
            app.alpha3EditFieldLabel = uilabel(app.DHparametersPanel);
            app.alpha3EditFieldLabel.Text = 'alpha3';
            app.alpha3EditField = uieditfield(app.DHparametersPanel, 'numeric');
            app.d1EditFieldLabel = uilabel(app.DHparametersPanel);
            app.d1EditFieldLabel.Text = 'd1';
            app.d1EditField = uieditfield(app.DHparametersPanel, 'numeric');
            app.d1EditField.Value = 0.052;
            app.d2EditFieldLabel = uilabel(app.DHparametersPanel);
            app.d2EditFieldLabel.Text = 'd2';
            app.d2EditField = uieditfield(app.DHparametersPanel, 'numeric');
            app.d2EditField.Value = 0.02;
            app.d3EditFieldLabel = uilabel(app.DHparametersPanel);
            app.d3EditFieldLabel.Text = 'd3';
            app.d3EditField = uieditfield(app.DHparametersPanel, 'numeric');
            app.d3EditField.Value = 0.02;
            app.EndEffectorLocationPanel = uipanel(app.UIFigure);
            app.EndEffectorLocationPanel.Title = 'End-Effector Location';
            app.XEditFieldLabel = uilabel(app.EndEffectorLocationPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.EndEffectorLocationPanel, 'numeric');
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @xPosChange, true);
            app.YEditFieldLabel = uilabel(app.EndEffectorLocationPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.EndEffectorLocationPanel, 'numeric');
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @yPosChange, true);
            app.ZEditFieldLabel = uilabel(app.EndEffectorLocationPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.EndEffectorLocationPanel, 'numeric');
            app.ZEditField.ValueChangedFcn = createCallbackFcn(app, @zPosChange, true);
            app.DistanceEditFieldLabel = uilabel(app.UIFigure);
            app.DistanceEditFieldLabel.Text = 'Distance';
            app.DistanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.PoseDropDownLabel = uilabel(app.UIFigure);
            app.PoseDropDownLabel.Text = 'Pose';
            app.PoseDropDown = uidropdown(app.UIFigure);
            app.PoseDropDown.Items = {'Pose1_000', 'Pose2_090', 'Pose3_0-90', 'Pose4_900', 'Pose5_990', 'Pose6_9-90', 'Pose7_-900', 'Pose8_-990', 'Pose9_-9-90'};
            app.PoseDropDown.ValueChangedFcn = createCallbackFcn(app, @ChangePose, true);
            app.PoseDropDown.Value = 'Pose2_090';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = G8RobotArm

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end