classdef TicTacToeApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TicTacToewithRobotArmUIFigure  matlab.ui.Figure
        OperationMenu                  matlab.ui.container.Menu
        OptimizeMenu                   matlab.ui.container.Menu
        TestRobotMenu                  matlab.ui.container.Menu
        ConfigMenu                     matlab.ui.container.Menu
        ConfigurationMenu              matlab.ui.container.Menu
        StartButton                    matlab.ui.control.Button
        GameInfoLabel                  matlab.ui.control.Label
        GameTextArea                   matlab.ui.control.TextArea
        TicTacToeGameBoardPanel        matlab.ui.container.Panel
        Image1                         matlab.ui.control.Image
        Image4                         matlab.ui.control.Image
        Image7                         matlab.ui.control.Image
        Image8                         matlab.ui.control.Image
        Image2                         matlab.ui.control.Image
        Image5                         matlab.ui.control.Image
        Image9                         matlab.ui.control.Image
        Image3                         matlab.ui.control.Image
        Image6                         matlab.ui.control.Image
        FisrtPlayButtonGroup           matlab.ui.container.ButtonGroup
        User1stButton                  matlab.ui.control.RadioButton
        Robot1stButton                 matlab.ui.control.RadioButton
        CurrentPlayingButtonGroup      matlab.ui.container.ButtonGroup
        CurrUserButton                 matlab.ui.control.RadioButton
        CurrRobotButton                matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        arduino_initialized = true;
        IsRobotCreated = false;
        optimized = false;
        ConfigApp             % Config Hardware Dialog box app        
        TestRobotApp
        game TicTacToe
        outputText
    end
    
    properties (Access = public)
        arduino_hardware = [];
        JointServo1 = [];
        JointServo2 = [];
        JointServo3 = [];
        G8Robot = [];
        data    % including all configuration data
                % format: name value
        DhPara          % [a1      a2      a3;
                        %  alpha1  alpha2  alpha3;
                        %  d1      d2      d3;
                        %  offset1 offset2 offset2;
                        %  baseX   baseY  baseZ]
        %definition of robot joint angle limits
        q = [0 0 0]         %unit:degree
        distance = 0        %EE distance
        position = [0 0 0]  %EE position
        qlim = [-112 90; -90 112;0 165];
        ComPort = "COM5"        % Initial Arduino COM port
        JointServoPin = {"D3" "D5" "D6"};
        JointServoPulseRange = [575 2460; 575 2460; 640 2250]
        %definition of game board
        grid = [0.16 -0.04 0.04 -0.04;  %(startX startY endX endY)
                0.16 -0.08 0.04 -0.08; 
                0.12  0.00 0.12 -0.12; 
                0.08  0.00 0.08 -0.12];
    end
    
    methods (Access = private)
        
        function updateRobot(app)
            if app.IsRobotCreated == true
                %Re-plot robot for new pose
                if isempty(app.JointServo1) == false
                    angle1 = ( app.qlim(1,2) - app.q(1))/...
                             (app.qlim(1,2) - app.qlim(1,1));
                    %angle1 = app.Joint1Spinner.Value;
                    writePosition(app.JointServo1, angle1);
                end
                
                if isempty(app.JointServo2) == false
                    angle2 = (app.qlim(2,2) - app.q(2))/...
                             (app.qlim(2,2) - app.qlim(2,1));
                    %angle2 = app.Joint2Spinner.Value;
                    writePosition(app.JointServo2, angle2);
                end
                
                
                app.G8Robot.plot([app.q(1)*pi/180, app.q(2)*pi/180, ...
                                  app.q(3)*pi/180])
                %Recalculate the Transformation matrix after joint angle
                %changed
                T = app.G8Robot.fkine([app.q(1)*pi/180, app.q(2)*pi/180, ...
                                  app.q(3)*pi/180]);
                %Update the EE position
                app.position = T.t;
                %Calculate the distance of EE
                app.distance = sqrt(T.t(1)^2 + T.t(2)^2 + T.t(3)^2);
            else
                msgbox("Robot not created yet")
            end
        end
        
        
        function results = minmax(~,x)
            results = min(max(x,0),1); % restricts input X between 0 and 1
        end
        
        function outPut(app,str)
            string = sprintf('%s', str);
            app.outputText=horzcat(app.outputText,string); 
            app.GameTextArea.Value = app.outputText;
            
        end

        
        function loadPicForRobot(app,position)
            switch position
                case 1
                    app.Image1.ImageSource = "O.png";
                case 2
                    app.Image2.ImageSource = "O.png";
                case 3
                    app.Image3.ImageSource = "O.png";
                case 4
                    app.Image4.ImageSource = "O.png";
                case 5
                    app.Image5.ImageSource = "O.png";
                case 6
                    app.Image6.ImageSource = "O.png";
                case 7
                    app.Image7.ImageSource = "O.png";
                case 8
                    app.Image8.ImageSource = "O.png";
                case 9
                    app.Image9.ImageSource = "O.png";
                otherwise
                    msgbox("robot select wrong position!")
            end
            
        end
        
        function loadPicForUser(app,position)
            switch position
                case 1
                    app.Image1.ImageSource = "X.png";
                case 2
                    app.Image2.ImageSource = "X.png";
                case 3
                    app.Image3.ImageSource = "X.png";
                case 4
                    app.Image4.ImageSource = "X.png";
                case 5
                    app.Image5.ImageSource = "X.png";
                case 6
                    app.Image6.ImageSource = "X.png";
                case 7
                    app.Image7.ImageSource = "X.png";
                case 8
                    app.Image8.ImageSource = "X.png";
                case 9
                    app.Image9.ImageSource = "X.png";
                otherwise
                    msgbox("robot select wrong position!")
            end
            
        end
        
        function clearBoard(app)
            app.Image1.ImageSource = "blank.png";
            app.Image2.ImageSource = "blank.png";
            app.Image3.ImageSource = "blank.png";
            app.Image4.ImageSource = "blank.png";
            app.Image5.ImageSource = "blank.png";
            app.Image6.ImageSource = "blank.png";
            app.Image7.ImageSource = "blank.png";
            app.Image8.ImageSource = "blank.png";
            app.Image9.ImageSource = "blank.png";
            
            figure(1)
            clf(1)
        end
        
        function disableImages(app)
            app.Image1.Enable = "off";
            app.Image2.Enable = "off";
            app.Image3.Enable = "off";
            app.Image4.Enable = "off";
            app.Image5.Enable = "off";
            app.Image6.Enable = "off";
            app.Image7.Enable = "off";
            app.Image8.Enable = "off";
            app.Image9.Enable = "off";
        end
        
        function enableImages(app)
            app.Image1.Enable = "on";
            app.Image2.Enable = "on";
            app.Image3.Enable = "on";
            app.Image4.Enable = "on";
            app.Image5.Enable = "on";
            app.Image6.Enable = "on";
            app.Image7.Enable = "on";
            app.Image8.Enable = "on";
            app.Image9.Enable = "on";
        end
        
        function userClick(app,position)
            % common function for user click image event
            app.disableImages();
            if app.game.state == "running"
                row = app.game.center(position,4);
                column = app.game.center(position,5);
                if (app.game.board(row,column) == 255) && (app.game.player == "user")
                    loadPicForUser(app,position);
                    app.game.drawX(position);
                    [done,who] = app.game.checkForWin();
                    if done == true
                        if who =='X'
                            msg = "You Win！";
                        elseif who == 'O'
                            msg = "You Lose!";
                        else
                            msg = "This is a draw!";
                        end
                        
                        outPut(app,msg);
                        outPut(app,"Game finished");
                        msgbox(msg)
                        endGame(app);
                    else
                        outPut(app,"robot is playing...")
                        app.game.player = "robot";
                        app.CurrRobotButton.Value = true;
                        positionO = app.game.computeNextMove();
                        app.game.drawO(positionO);
                        loadPicForRobot(app,positionO);
                        [done,who] = app.game.checkForWin();
                        if done == true
                            if who =='X'
                                msg = "You Win！";
                            elseif who == 'O'
                                msg = "You Lose!";
                            else
                                msg = "This is a draw!";
                            end
                            
                            outPut(app,msg);
                            outPut(app,"Game finished");
                            msgbox(msg);
                            endGame(app);
                        else
                            app.game.player = "user";
                            app.CurrUserButton.Value = true;
                            outPut(app,"user is playing...")
                        end
                    end
                    
                end
            end
            app.enableImages();
        end
        
        
        function endGame(app)
            app.User1stButton.Enable = "on";
            app.Robot1stButton.Enable = "on";
            app.game.state = "done";
            app.StartButton.Text = "Start";
            app.StartButton.Enable = "on";
        end
    end
    
    methods (Access = public)
        
        
        function updateSysConfig(app, data, port, servoPin)
            app.data = data;
            app.ComPort = port;     %Update port
            app.JointServoPin = servoPin;
            %re-enable config arduino port
            app.ConfigurationMenu.Enable = 'on';
        end
        
        function uploadConfigPara(app)
            %read all configuration data from file
            app.data = readcell('config.txt');
        end
        function saveConfigData(app)
            %save all configuration data to file
            fileID = fopen('config.txt','w');
            formatSpec = '%s %f\n';
            [nrows,ncols] = size(app.data);
            for row = 1:nrows
                fprintf(fileID,formatSpec,app.data{row,:});
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.outputText{1} = sprintf('%s', '');
            app.GameTextArea.Value=app.outputText{1};
           
            uploadConfigPara(app);
            app.DhPara(1,1) = app.data{strcmp("DH_a1",app.data),2};
            app.DhPara(1,2) = app.data{strcmp("DH_a2",app.data),2};
            app.DhPara(1,3) = app.data{strcmp("DH_a3",app.data),2};
            app.DhPara(2,1) = app.data{strcmp("DH_alpha1",app.data),2};
            app.DhPara(2,2) = app.data{strcmp("DH_alpha2",app.data),2};
            app.DhPara(2,3) = app.data{strcmp("DH_alpha3",app.data),2};
            app.DhPara(3,1) = app.data{strcmp("DH_d1",app.data),2};
            app.DhPara(3,2) = app.data{strcmp("DH_d2",app.data),2};
            app.DhPara(3,3) = app.data{strcmp("DH_d3",app.data),2};
            app.DhPara(4,1) = app.data{strcmp("DH_offset1",app.data),2};
            app.DhPara(4,2) = app.data{strcmp("DH_offset2",app.data),2};
            app.DhPara(4,3) = app.data{strcmp("DH_offset3",app.data),2};
            app.DhPara(5,1) = app.data{strcmp("DH_baseX",app.data),2};
            app.DhPara(5,2) = app.data{strcmp("DH_baseY",app.data),2};
            app.DhPara(5,3) = app.data{strcmp("DH_baseZ",app.data),2};
            
        end

        % Key release function: TicTacToewithRobotArmUIFigure
        function TicTacToewithRobotArmUIFigureKeyRelease(app, event)
            %make sure robot is created already
            if app.IsRobotCreated == true
                %Get current robot EE transformation matrix
                q1 = [app.Joint1Slider.Value*pi/180 ...
                    app.Joint2Slider.Value*pi/180 ...
                    app.Joint3Slider.Value*pi/180];
                T = app.G8Robot.fkine(q1);
                %adjust x or y position according to key press
                if event.Key == "leftarrow"
                    T.t(2) = T.t(2) + 0.005;    %move to Y + 0.005m
                elseif event.Key == "rightarrow"
                    T.t(2) = T.t(2) - 0.005;    %move to Y - 0.005m
                elseif event.Key == "uparrow"
                    T.t(1) = T.t(1) + 0.005;    %move to X + 0.005m
                elseif event.Key == "downarrow"
                    T.t(1) = T.t(1) - 0.005;    %move to X - 0.005m
                else 
                end
                %Do inverse kinematics
                q2 = app.G8Robot.ikine(T,'q0',q1,'mask',[1 1 1 0 0 0]);
                if (isempty(q2) == false)
                    %update joints angle after ikine
                    q2deg = rad2deg(q2);
                    if((q2deg(1) <= app.Joint1Slider.Limits(2)) && ...
                       (q2deg(1) >= app.Joint1Slider.Limits(1)) && ...
                       (q2deg(2) <= app.Joint2Slider.Limits(2)) && ...
                       (q2deg(2) >= app.Joint2Slider.Limits(1)))
                   
                        app.Joint1Slider.Value = q2deg(1);
                        app.Joint2Slider.Value = q2deg(2);
                        app.Joint3Slider.Value = q2deg(3);
                        %plot the robot and update postion and distance info
                        app.updateRobot();
                    else
                        msg = sprintf("Joint angle reaches limit:\n" + ...
                                      "Joint1 angle:%0.1f\n" + ...
                                      "Joint2 angle:%0.1f",q2deg(1),q2deg(2));
                        msgbox(msg);
                    end
                else
                    msgbox("can't reach the target");
                end
            else
                msgbox("Robot not created yet")
            end
        end

        % Menu selected function: OptimizeMenu
        function Optimize(app, event)
            %before start, set flag as flase
            app.optimized = false;
            
            %set optimization options
            options = optimset('Display','final','TolX',1e-6, ...
                'PlotFcns',@optimplotfval,'MaxIter',500);
            %start optimization
            [x,fval,exitflag,output] = fminsearch(@CalcDistError,app.DhPara, options)
            
            app.optimized = true;
            %optimization finished, update the DH parameters
            app.DhPara = x;
            
            %Store updated DH parameter to system data
            app.data{strcmp("DH_a1",app.data),2} = x(1,1);
            app.data{strcmp("DH_a2",app.data),2} = x(1,2);
            app.data{strcmp("DH_a3",app.data),2} = x(1,3);
            app.data{strcmp("DH_alpha1",app.data),2} = x(2,1);
            app.data{strcmp("DH_alpha2",app.data),2} = x(2,2);
            app.data{strcmp("DH_alpha3",app.data),2} = x(2,3);
            app.data{strcmp("DH_d1",app.data),2} = x(3,1);
            app.data{strcmp("DH_d2",app.data),2} = x(3,2);
            app.data{strcmp("DH_d3",app.data),2} = x(3,3);
            app.data{strcmp("DH_offset1",app.data),2} = x(4,1);
            app.data{strcmp("DH_offset2",app.data),2} = x(4,2);
            app.data{strcmp("DH_offset3",app.data),2} = x(4,3);
            app.data{strcmp("DH_baseX",app.data),2} = x(5,1);
            app.data{strcmp("DH_baseY",app.data),2} = x(5,2);
            app.data{strcmp("DH_baseZ",app.data),2} = x(5,3);

        end

        % Menu selected function: ConfigurationMenu
        function ConfigSysPara(app, event)
            %Disable the menu to avoid reopen config window 
            app.ConfigurationMenu.Enable = 'off';
            
            % Open the options dialog and pass inputs
            app.ConfigApp = ConfigurationDialog(app, app.data, app.ComPort, app.JointServoPin);
            
        end

        % Close request function: TicTacToewithRobotArmUIFigure
        function TicTacToewithRobotArmUIFigureCloseRequest(app, event)
            saveConfigData(app);
            
            %close all windows
            close all
            
            % Delete both apps
            delete(app.ConfigApp)
            delete(app.TestRobotApp)
            delete(app)
            
        end

        % Callback function
        function UpdateServo(app, event)

            app.updateRobot();
        end

        % Button pushed function: StartButton
        function StartGame(app, event)
            if app.StartButton.Text == "Start"
                app.StartButton.Text = "Stop";
                app.User1stButton.Enable = "off";
                app.Robot1stButton.Enable = "off";
                app.disableImages();
                app.GameTextArea.Value = '';
                %check robot created
                %check HW connected
                
                %clear board
                clearBoard(app)
                
                %Initialize game
                app.game = TicTacToe;
                outPut(app,"Game initialized.")
                app.game.createRobot();
                outPut(app,"Robot model created.")
                app.game.servoPwmRange = [app.data{strcmp("Servo1MinPulse",app.data),2}*10^(-6), ...
                    app.data{strcmp("Servo1MaxPulse",app.data),2}*10^(-6); ...
                    app.data{strcmp("Servo2MinPulse",app.data),2}*10^(-6), ...
                    app.data{strcmp("Servo2MaxPulse",app.data),2}*10^(-6); ...
                    app.data{strcmp("Servo3MinPulse",app.data),2}*10^(-6), ...
                    app.data{strcmp("Servo3MaxPulse",app.data),2}*10^(-6)];
                [result, warnmsg] = app.game.connectHW(app.ComPort,app.JointServoPin);
                app.game.isDrawMoveTraj = app.data{strcmp("isDrawMoveTraj",app.data),2};
                app.game.isRecordTraj = app.data{strcmp("isRecordTraj",app.data),2};
                app.game.isUseFileTraj = app.data{strcmp("isUseFileTraj",app.data),2};
            
                if result == 0
                    outPut(app,"Robot Hardware connected.")
                else
                    outPut(app,warnmsg)
                    outPut(app, "Only simulation mode")
                end
                app.game.state = "ready";
                
                outPut(app,"Waiting, drawing grid");
                app.game.drawGrid(app.grid);
                outPut(app,"Game board setup finished.");
                app.game.state = "running";
                outPut(app,"Ready to play.");
                app.enableImages();
                
                if app.User1stButton.Value == true
                    app.game.player = "user";
                    app.CurrUserButton.Value = true;
                    outPut(app,"Now user to play")
                else
                    app.game.player = "robot";
                    app.CurrRobotButton.Value = true;
                    outPut(app,"Now Robot to play")
                    positionO = app.game.computeNextMove();
                    app.game.drawO(positionO);
                    loadPicForRobot(app,positionO);

                    app.game.player = "user";
                    app.CurrUserButton.Value = true;
                    outPut(app,"user is playing...")

                end
            elseif app.StartButton.Text == "Stop"
                endGame(app);
            else
            end
        end

        % Menu selected function: TestRobotMenu
        function TestRobot(app, event)
            %Disable the menu to avoid reopen config window 
            app.TestRobotMenu.Enable = 'off';
            
            % Open the options dialog and pass inputs
            app.TestRobotApp = TestRobotDialog(app);
        end

        % Image clicked function: Image1
        function ClickOnPic1(app, event)
            userClick(app,1);
        end

        % Image clicked function: Image2
        function ClickOnPic2(app, event)
            userClick(app,2);
        end

        % Image clicked function: Image3
        function ClickOnPic3(app, event)
            userClick(app,3);

        end

        % Image clicked function: Image4
        function ClickOnPic4(app, event)
            userClick(app,4);

        end

        % Image clicked function: Image5
        function ClickOnPic5(app, event)
            userClick(app,5);

        end

        % Image clicked function: Image6
        function ClickOnPic6(app, event)
            userClick(app,6);

        end

        % Image clicked function: Image7
        function ClickOnPic7(app, event)
            userClick(app,7);

        end

        % Image clicked function: Image8
        function ClickOnPic8(app, event)
            userClick(app,8);

        end

        % Image clicked function: Image9
        function ClickOnPic9(app, event)
            userClick(app,9);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TicTacToewithRobotArmUIFigure = uifigure('Visible', 'off');
            app.TicTacToewithRobotArmUIFigure.Name = 'TicTacToe with Robot Arm';
            app.TicTacToewithRobotArmUIFigure.CloseRequestFcn = createCallbackFcn(app, @TicTacToewithRobotArmUIFigureCloseRequest, true);
            app.TicTacToewithRobotArmUIFigure.KeyReleaseFcn = createCallbackFcn(app, @TicTacToewithRobotArmUIFigureKeyRelease, true);
            app.OperationMenu = uimenu(app.TicTacToewithRobotArmUIFigure);
            app.OperationMenu.Text = 'Operation';
            app.OptimizeMenu = uimenu(app.OperationMenu);
            app.OptimizeMenu.MenuSelectedFcn = createCallbackFcn(app, @Optimize, true);
            app.OptimizeMenu.Text = 'Optimize';
            app.TestRobotMenu = uimenu(app.OperationMenu);
            app.TestRobotMenu.MenuSelectedFcn = createCallbackFcn(app, @TestRobot, true);
            app.TestRobotMenu.Text = 'TestRobot';
            app.ConfigMenu = uimenu(app.TicTacToewithRobotArmUIFigure);
            app.ConfigMenu.Text = 'Config';
            app.ConfigurationMenu = uimenu(app.ConfigMenu);
            app.ConfigurationMenu.MenuSelectedFcn = createCallbackFcn(app, @ConfigSysPara, true);
            app.ConfigurationMenu.Text = 'Configuration';
            app.StartButton = uibutton(app.TicTacToewithRobotArmUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartGame, true);
            app.StartButton.Text = 'Start';
            app.GameInfoLabel = uilabel(app.TicTacToewithRobotArmUIFigure);
            app.GameInfoLabel.Text = 'GameInfo';
            app.GameTextArea = uitextarea(app.TicTacToewithRobotArmUIFigure);
            app.GameTextArea.Editable = 'off';
            app.TicTacToeGameBoardPanel = uipanel(app.TicTacToewithRobotArmUIFigure);
            app.TicTacToeGameBoardPanel.Title = 'TicTacToe Game Board';
            app.Image1 = uiimage(app.TicTacToeGameBoardPanel);
            app.Image1.ImageClickedFcn = createCallbackFcn(app, @ClickOnPic1, true);
            app.Image1.ImageSource = 'blank.png';
            app.Image4 = uiimage(app.TicTacToeGameBoardPanel);
            app.Image4.ImageClickedFcn = createCallbackFcn(app, @ClickOnPic4, true);
            app.Image4.ImageSource = 'blank.png';
            app.Image7 = uiimage(app.TicTacToeGameBoardPanel);
            app.Image7.ImageClickedFcn = createCallbackFcn(app, @ClickOnPic7, true);
            app.Image7.ImageSource = 'blank.png';
            app.Image8 = uiimage(app.TicTacToeGameBoardPanel);
            app.Image8.ImageClickedFcn = createCallbackFcn(app, @ClickOnPic8, true);
            app.Image8.ImageSource = 'blank.png';
            app.Image2 = uiimage(app.TicTacToeGameBoardPanel);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @ClickOnPic2, true);
            app.Image2.ImageSource = 'blank.png';
            app.Image5 = uiimage(app.TicTacToeGameBoardPanel);
            app.Image5.ImageClickedFcn = createCallbackFcn(app, @ClickOnPic5, true);
            app.Image5.ImageSource = 'blank.png';
            app.Image9 = uiimage(app.TicTacToeGameBoardPanel);
            app.Image9.ImageClickedFcn = createCallbackFcn(app, @ClickOnPic9, true);
            app.Image9.ImageSource = 'blank.png';
            app.Image3 = uiimage(app.TicTacToeGameBoardPanel);
            app.Image3.ImageClickedFcn = createCallbackFcn(app, @ClickOnPic3, true);
            app.Image3.ImageSource = 'blank.png';
            app.Image6 = uiimage(app.TicTacToeGameBoardPanel);
            app.Image6.ImageClickedFcn = createCallbackFcn(app, @ClickOnPic6, true);
            app.Image6.ImageSource = 'blank.png';
            app.FisrtPlayButtonGroup = uibuttongroup(app.TicTacToewithRobotArmUIFigure);
            app.FisrtPlayButtonGroup.Title = 'Fisrt Play';
            app.User1stButton = uiradiobutton(app.FisrtPlayButtonGroup);
            app.User1stButton.Text = 'User';
            app.User1stButton.Value = true;
            app.Robot1stButton = uiradiobutton(app.FisrtPlayButtonGroup);
            app.Robot1stButton.Text = 'Robot';
            app.CurrentPlayingButtonGroup = uibuttongroup(app.TicTacToewithRobotArmUIFigure);
            app.CurrentPlayingButtonGroup.Title = 'Current Playing';
            app.CurrUserButton = uiradiobutton(app.CurrentPlayingButtonGroup);
            app.CurrUserButton.Enable = 'off';
            app.CurrUserButton.Text = 'User';
            app.CurrUserButton.Value = true;
            app.CurrRobotButton = uiradiobutton(app.CurrentPlayingButtonGroup);
            app.CurrRobotButton.Enable = 'off';
            app.CurrRobotButton.Text = 'Robot';
            % Show the figure after all components are created
            app.TicTacToewithRobotArmUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TicTacToeApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TicTacToewithRobotArmUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TicTacToewithRobotArmUIFigure)
        end
    end
end